classdef proyecto_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        TablaEbN0Panel    matlab.ui.container.Panel
        TablaDelta        matlab.ui.control.Table
        TablaButton       matlab.ui.control.Button
        MetricasPanel     matlab.ui.container.Panel
        MetricasTextArea  matlab.ui.control.TextArea
        ControlesPanel    matlab.ui.container.Panel
        SimularButton     matlab.ui.control.Button
        NEditField        matlab.ui.control.NumericEditField
        NsimbolosLabel    matlab.ui.control.Label
        EbNoEditField     matlab.ui.control.NumericEditField
        EbN0dBLabel       matlab.ui.control.Label
        BetaEditField     matlab.ui.control.NumericEditField
        BetaLabel         matlab.ui.control.Label
        MEditField        matlab.ui.control.NumericEditField
        Label_2           matlab.ui.control.Label
        MLabel            matlab.ui.control.Label
        TipoDropDown      matlab.ui.control.DropDown
        Label             matlab.ui.control.Label
        TipoLabel         matlab.ui.control.Label
        UIAxes5           matlab.ui.control.UIAxes
        UIAxes4           matlab.ui.control.UIAxes
        UIAxes3           matlab.ui.control.UIAxes
        UIAxes2           matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
         %% === 1. Leer parámetros de la GUI ===
            tipo    = app.TipoDropDown.Value;
            M       = round(app.MEditField.Value);
            beta    = app.BetaEditField.Value;
            EbNo_dB = app.EbNoEditField.Value;
            N       = round(app.NEditField.Value);
            
            %% === 2. VALIDACIÓN (RNF1) ===
            if beta < 0 || beta > 1
                uialert(app.UIFigure, 'Beta (roll-off) debe estar entre 0 y 1.', 'Error');
                return;
            end
            if M < 2 || mod(log2(M), 1) ~= 0
                uialert(app.UIFigure, 'M debe ser una potencia de 2 (2, 4, 8, 16, ...).', 'Error');
                return;
            end
            if strcmp(tipo, 'QAM') && mod(log2(M), 2) ~= 0
                uialert(app.UIFigure, 'Para QAM, M debe ser potencia de 4 (4, 16, 64, 256...).', 'Error');
                return;
            end
            if M > 16 && strcmp(tipo, 'QAM')
                uialert(app.UIFigure, 'Advertencia: Para M > 16, la codificación Gray 2D no es 100% óptima.', 'Advertencia Teórica', 'Icon', 'warning');
            end
            if N < 100 || N > 1e6
                uialert(app.UIFigure, 'N debe estar entre 100 y 1,000,000.', 'Error');
                return;
            end
            if EbNo_dB < 0 || EbNo_dB > 40
                uialert(app.UIFigure, 'Eb/N0 debe estar entre 0 y 40 dB.', 'Error');
                return;
            end
            
            %% === 3. Parámetros fijos y Mapeo ===
            span = 6; sps = 8; fc = 0.15;
            switch upper(tipo)
                case 'PAM', [simbolos, bits_tx, k] = mapeo_pam(M, N);
                case 'QAM', [simbolos, bits_tx, k] = mapeo_qam(M, N);
                case 'PSK', [simbolos, bits_tx, k] = mapeo_psk(M, N);
            end
            
            %% === 4. Sistema Real en Pasa Banda (Limpio y Corregido) ===
            % -- Filtro Tx (Banda Base) --
            h       = filtro_srrc(beta, span, sps);
            retardo = span * sps;
            simb_up = mi_upsample(simbolos, sps);
            sen_tx  = conv(simb_up, h, 'full'); 
            
            % -- Modulación a Pasa Banda --
            n_pb = (0:length(sen_tx)-1)';
            sen_pb_tx = real(sen_tx .* exp(1j*2*pi*fc*n_pb));
            
            % -- Canal AWGN (Ruido real) --
            sen_pb_rx = canal_awgn(sen_pb_tx, EbNo_dB, k);
            
            % -- Demodulación I/Q (Receptor Físico Real, SIN filtro extra) --
            I_rx = 2 * sen_pb_rx .* cos(2*pi*fc*n_pb);
            Q_rx = -2 * sen_pb_rx .* sin(2*pi*fc*n_pb);
            sen_bb_rx = I_rx + 1j*Q_rx;
            
            % -- Filtro Rx (SRRC) y muestreo --
            sen_fil  = conv(sen_bb_rx, h, 'full');
            muestras = sen_fil(retardo+1 : sps : retardo + N*sps);
            
            % -- Decisión --
            C = constelacion(M, tipo);
            [bits_rx, ~] = decisor(muestras, C, k);
            BER = sum(bits_tx ~= bits_rx) / length(bits_tx);
            
            %% === 5. Gráfica 1: Constelación Rx ===
            cla(app.UIAxes);
            plot(app.UIAxes, real(muestras), imag(muestras), 'bo', 'MarkerSize', 3);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, real(C), imag(C), 'rx', 'MarkerSize', 10, 'LineWidth', 2);
            hold(app.UIAxes, 'off'); grid(app.UIAxes, 'on'); axis(app.UIAxes, 'equal');
            title(app.UIAxes, sprintf('Constelación Rx %d-%s', M, tipo));
            
            %% === 6. Gráfica 2: Diagrama del ojo ===
            sen_fil_ojo = sen_fil(retardo+1 : end-retardo);
            L = 2*sps;
            num_seg = min(floor((length(sen_fil_ojo) - L)/sps), 400);
            segmentos = zeros(num_seg, L);
            for i = 1:num_seg
                segmentos(i,:) = sen_fil_ojo((i-1)*sps+1 : (i-1)*sps+L).';
            end
            cla(app.UIAxes2); hold(app.UIAxes2, 'on');
            t_ojo = (0:L-1)/sps;
            for i = 1:num_seg
                plot(app.UIAxes2, t_ojo, real(segmentos(i,:)), 'Color', [0.6 0.6 0.9]);
            end
            xline(app.UIAxes2, 1, 'r--', 'LineWidth', 1.5); hold(app.UIAxes2, 'off');
            grid(app.UIAxes2, 'on'); title(app.UIAxes2, sprintf('Diagrama del ojo (%.0f dB)', EbNo_dB));
            xlim(app.UIAxes2, [0 2]);
            
            %% === 7. Gráfica 3: Curva BER (Bucle en Pasa Banda, SIN filtro extra) ===
            EbNo_range = 0:2:14;
            ber_curva  = zeros(size(EbNo_range));
            for i = 1:length(EbNo_range)
                pb_rx_loop = canal_awgn(sen_pb_tx, EbNo_range(i), k);
                I_loop = 2 * pb_rx_loop .* cos(2*pi*fc*n_pb);
                Q_loop = -2 * pb_rx_loop .* sin(2*pi*fc*n_pb);
                sf = conv(I_loop + 1j*Q_loop, h, 'full');
                mu = sf(retardo+1 : sps : retardo + N*sps);
                [br, ~] = decisor(mu, C, k);
                ber_curva(i) = sum(bits_tx ~= br) / length(bits_tx);
            end
            ber_plot = ber_curva; ber_plot(ber_plot == 0) = 1e-6;
            cla(app.UIAxes3);
            semilogy(app.UIAxes3, EbNo_range, ber_plot, 'b-o', 'LineWidth', 1.5, 'MarkerSize', 6);
            grid(app.UIAxes3, 'on'); title(app.UIAxes3, 'Curva BER vs Eb/N0');
            ylim(app.UIAxes3, [1e-6 1]);
            
            %% === 8. Gráfica 4: Espectro banda base ===
            N_fft = 2048; L_fft = length(sen_tx);
            win = 0.5 * (1 - cos(2*pi*(0:L_fft-1)'/(L_fft-1)));
            X_bb = fftshift(fft(sen_tx .* win, N_fft));
            PSD_bb = abs(X_bb).^2 / (N_fft * sum(win.^2));
            PSD_bb_dB = 10*log10(PSD_bb / max(PSD_bb));
            f_bb = (-N_fft/2 : N_fft/2-1) / N_fft;
            
            cla(app.UIAxes4);
            plot(app.UIAxes4, f_bb, PSD_bb_dB, 'b', 'LineWidth', 1.2);
            grid(app.UIAxes4, 'on'); title(app.UIAxes4, 'Espectro banda base (Tx)');
            xlim(app.UIAxes4, [-0.5 0.5]); ylim(app.UIAxes4, [-80 5]);
            
            %% === 9. Gráfica 5: Espectro pasa banda (Rx) ===
            X_pb = fftshift(fft(sen_pb_rx .* win, N_fft));
            PSD_pb = abs(X_pb).^2 / (N_fft * sum(win.^2));
            PSD_pb_dB = 10*log10(PSD_pb / max(PSD_pb));
            f_pb = (-N_fft/2 : N_fft/2-1) / N_fft;
            
            cla(app.UIAxes5);
            plot(app.UIAxes5, f_pb, PSD_pb_dB, 'r', 'LineWidth', 1.2);
            grid(app.UIAxes5, 'on'); title(app.UIAxes5, sprintf('Espectro pasa banda (fc=%.2f)', fc));
            xlim(app.UIAxes5, [-0.5 0.5]); ylim(app.UIAxes5, [-80 5]);
            
            %% === 10. Métricas (RF4) ===
            eta  = k / (1 + beta); Es = mean(abs(simbolos).^2);
            BW   = 1 * (1 + beta); P_bb = mean(abs(sen_tx).^2);
            texto = sprintf('η  = %.3f bits/s/Hz\nEs = %.4f\nBW = %.3f\nP  = %.4f\nBER = %.2e', eta, Es, BW, P_bb, BER);
            app.MetricasTextArea.Value = texto;
        end

        % Button pushed function: TablaButton
        function TablaButtonPushed(app, event)
           %% === 1. PARÁMETROS PARA LA SIMULACIÓN ===
            beta = app.BetaEditField.Value;
            span = 6; sps = 8; fc = 0.15; M_val = 4;
            N_val = 50000; % Suficiente para una buena estadística (50000 x 2 bits = 100k bits)
            
            BER_obj = [1e-2, 1e-3, 1e-4, 1e-5, 1e-6, 1e-7];
            EbNo_range = 0:0.5:14; % Barrido fino para interpolar
            
            tipos = {'PAM', 'QAM', 'PSK'};
            
            % Barra de progreso
            d = uiprogressdlg(app.UIFigure, 'Title', 'Simulando Tabla...', ...
                'Message', 'Calculando curvas reales en Pasa Banda...', 'Indeterminate', 'on');
            
            h = filtro_srrc(beta, span, sps);
            retardo = span * sps;
            ber_mat = zeros(length(EbNo_range), 3);
            
            %% === 2. SIMULACIÓN FÍSICA DE LOS 3 ESQUEMAS ===
            for t = 1:3
                d.Message = sprintf('Simulando 4-%s...', tipos{t});
                switch tipos{t}
                    case 'PAM', [simb, bits_tx, k] = mapeo_pam(M_val, N_val);
                    case 'QAM', [simb, bits_tx, k] = mapeo_qam(M_val, N_val);
                    case 'PSK', [simb, bits_tx, k] = mapeo_psk(M_val, N_val);
                end
                C = constelacion(M_val, tipos{t});
                
                % Tx y Modulación
                sen_bb_tx = conv(mi_upsample(simb, sps), h, 'full');
                n = (0:length(sen_bb_tx)-1)';
                sen_pb_tx = real(sen_bb_tx .* exp(1j*2*pi*fc*n));
                
                % Barrido de Eb/N0
                for i = 1:length(EbNo_range)
                    sen_pb_rx = canal_awgn(sen_pb_tx, EbNo_range(i), k);
                    I_rx = 2 * sen_pb_rx .* cos(2*pi*fc*n);
                    Q_rx = -2 * sen_pb_rx .* sin(2*pi*fc*n);
                    sen_bb_rx = I_rx + 1j*Q_rx;
                    
                    sen_fil = conv(sen_bb_rx, h, 'full');
                    muestras = sen_fil(retardo+1 : sps : retardo + N_val*sps);
                    
                    [bits_rx, ~] = decisor(muestras, C, k);
                    ber_mat(i, t) = sum(bits_tx ~= bits_rx) / length(bits_tx);
                end
            end
            
            %% === 3. INTERPOLACIÓN PARA ENCONTRAR LOS Eb/N0 ===
            d.Message = 'Interpolando resultados...';
            EbNo_tabla = nan(length(BER_obj), 3);
            
            for t = 1:3
                mask = ber_mat(:,t) > 0;
                log_ber = log10(ber_mat(mask,t));
                ebno_v  = EbNo_range(mask)';
                
                % Eliminar duplicados promediando
                [log_ber, ~, ic] = unique(log_ber);
                ebno_v = accumarray(ic, ebno_v, [], @mean);
                
                ber_min = 10^min(log_ber);
                ber_max = 10^max(log_ber);
                
                for j = 1:length(BER_obj)
                    lo = log10(BER_obj(j));
                    if BER_obj(j) >= ber_min && BER_obj(j) <= ber_max
                        % Interpolación
                        EbNo_tabla(j,t) = interp1(log_ber, ebno_v, lo, 'linear');
                    else
                        % Extrapolación (para valores fuera del rango simulado)
                        mask_fit = log_ber >= -4 & log_ber <= -2;
                        if sum(mask_fit) >= 2
                            p = polyfit(log_ber(mask_fit), ebno_v(mask_fit), 1);
                        else
                            p = polyfit(log_ber, ebno_v, 1);
                        end
                        EbNo_tabla(j,t) = polyval(p, lo);
                    end
                end
            end
            
            %% === 4. CALCULAR DELTAS Y LLENAR LA TABLA ===
            d1 = EbNo_tabla(:,1) - EbNo_tabla(:,2);   % 4PAM - 4QAM
            d2 = EbNo_tabla(:,3) - EbNo_tabla(:,2);   % 4PSK - 4QAM
            d3 = EbNo_tabla(:,3) - EbNo_tabla(:,1);   % 4PSK - 4PAM
            Delta = [d1, d2, d3];
            
            BER_labels = {'10⁻²', '10⁻³', '10⁻⁴', '10⁻⁵', '10⁻⁶', '10⁻⁷'}';
            data_tabla = cell(length(BER_labels), 4);
            for j = 1:length(BER_labels)
                data_tabla{j, 1} = BER_labels{j};
                data_tabla{j, 2} = sprintf('%+.2f', Delta(j, 1));
                data_tabla{j, 3} = sprintf('%+.2f', Delta(j, 2));
                data_tabla{j, 4} = sprintf('%+.2f', Delta(j, 3));
            end
            app.TablaDelta.Data = data_tabla;
            close(d);
            
            %% === 5. GRÁFICA DE BARRAS ===
            figure('Name', 'ΔEb/N0 (Simulado)', 'Position', [100 100 800 400]);
            b = bar(Delta, 'grouped'); grid on;
            b(1).FaceColor = [0.2 0.4 0.8]; 
            b(2).FaceColor = [0.2 0.7 0.3]; 
            b(3).FaceColor = [0.8 0.2 0.2];
            set(gca, 'XTickLabel', {'10^{-2}','10^{-3}','10^{-4}','10^{-5}','10^{-6}','10^{-7}'});
            xlabel('BER'); ylabel('\Delta E_b/N_0 (dB)');
            title('Diferencias de E_b/N_0 (Simulación Pasa Banda)');
            legend({'4PAM - 4QAM', '4PSK - 4QAM', '4PSK - 4PAM'}, 'Location', 'best');
            yline(0, 'k-');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Constelacion Rx')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Diagrama del ojo')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Curva BER')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, 'Espectro')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.GridLayout);
            title(app.UIAxes5, 'Espectro pasa banda')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.ControlesPanel = uipanel(app.GridLayout);
            app.ControlesPanel.Title = 'Controles';
            app.TipoLabel = uilabel(app.ControlesPanel);
            app.TipoLabel.Text = 'Tipo: ';
            app.Label = uilabel(app.ControlesPanel);
            app.Label.Text = '';
            app.TipoDropDown = uidropdown(app.ControlesPanel);
            app.TipoDropDown.Items = {'PAM', 'QAM', 'PSK'};
            app.TipoDropDown.Value = 'QAM';
            app.MLabel = uilabel(app.ControlesPanel);
            app.MLabel.Text = 'M: ';
            app.Label_2 = uilabel(app.ControlesPanel);
            app.Label_2.Text = '';
            app.MEditField = uieditfield(app.ControlesPanel, 'numeric');
            app.MEditField.Value = 16;
            app.BetaLabel = uilabel(app.ControlesPanel);
            app.BetaLabel.Text = 'Beta: ';
            app.BetaEditField = uieditfield(app.ControlesPanel, 'numeric');
            app.BetaEditField.Value = 0.35;
            app.EbN0dBLabel = uilabel(app.ControlesPanel);
            app.EbN0dBLabel.Text = 'Eb/N0 (dB): ';
            app.EbNoEditField = uieditfield(app.ControlesPanel, 'numeric');
            app.EbNoEditField.Value = 10;
            app.NsimbolosLabel = uilabel(app.ControlesPanel);
            app.NsimbolosLabel.Text = 'N (simbolos):';
            app.NEditField = uieditfield(app.ControlesPanel, 'numeric');
            app.NEditField.Value = 5000;
            app.SimularButton = uibutton(app.ControlesPanel, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.MetricasPanel = uipanel(app.GridLayout);
            app.MetricasPanel.Title = 'Metricas';
            app.MetricasTextArea = uitextarea(app.MetricasPanel);
            app.MetricasTextArea.Editable = 'off';
            app.MetricasTextArea.Value = {'η = ---'};
            app.TablaEbN0Panel = uipanel(app.GridLayout);
            app.TablaEbN0Panel.Title = 'Tabla ΔEb/N0';
            app.TablaButton = uibutton(app.TablaEbN0Panel, 'push');
            app.TablaButton.ButtonPushedFcn = createCallbackFcn(app, @TablaButtonPushed, true);
            app.TablaButton.Text = 'Generar';
            app.TablaDelta = uitable(app.TablaEbN0Panel);
            app.TablaDelta.ColumnName = {'BER'; '4PAM-4QAM'; '4PSK-4QAM'; '4PSK-4PAM'};
            app.TablaDelta.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proyecto_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end